classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AtrialFibrilationApp         matlab.ui.Figure
        ArchivoMenu                  matlab.ui.container.Menu
        CargarSealMenu               matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        InicioTab                    matlab.ui.container.Tab
        Image2                       matlab.ui.control.Image
        Image4                       matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        INTEGRANTESESTALINGUNZAMICHAELPONCELabel  matlab.ui.control.Label
        SealTab                      matlab.ui.container.Tab
        Image7                       matlab.ui.control.Image
        ParmetrosTab                 matlab.ui.container.Tab
        Image6                       matlab.ui.control.Image
        Inicio                       matlab.ui.container.Tab
        Image5                       matlab.ui.control.Image
        DomT                         matlab.ui.container.Tab
        AxesDT                       matlab.ui.control.UIAxes
        SeleccionelagrficaquedeseeaobservarLabel  matlab.ui.control.Label
        FrecuenciaHzEditFieldLabel   matlab.ui.control.Label
        txtFrecuencia                matlab.ui.control.EditField
        ParmetrosdelaSealECGingresadaLabel  matlab.ui.control.Label
        GainEditFieldLabel           matlab.ui.control.Label
        txtGain                      matlab.ui.control.EditField
        cbOriginal                   matlab.ui.control.CheckBox
        cbProcesada                  matlab.ui.control.CheckBox
        cbPQRS                       matlab.ui.control.CheckBox
        cbDistancia                  matlab.ui.control.CheckBox
        txtMensaje                   matlab.ui.control.TextArea
        DomF                         matlab.ui.container.Tab
        AxesDF                       matlab.ui.control.UIAxes
        SeleccioneelarchivoquedeseaanalizarLabel  matlab.ui.control.Label
        MACHINELEARNINGPanel         matlab.ui.container.Panel
        btnSVM                       matlab.ui.control.Button
        tbCaracteristica             matlab.ui.control.Table
        DISTANCIAALCENTROIDEPanel    matlab.ui.container.Panel
        btnCentroide                 matlab.ui.control.Button
        AnchodeBandaEditFieldLabel   matlab.ui.control.Label
        txtABanda                    matlab.ui.control.EditField
        FMediaEditFieldLabel         matlab.ui.control.Label
        txtFMedia                    matlab.ui.control.EditField
        DistanciasLabel              matlab.ui.control.Label
        CentroideSanaLabel           matlab.ui.control.Label
        txtDistSana                  matlab.ui.control.EditField
        CentroideAFLabel             matlab.ui.control.Label
        txtDistAF                    matlab.ui.control.EditField
        btnCaracteristica            matlab.ui.control.Button
        ResultadodeAnlisisLabel      matlab.ui.control.Label
        MachineLearningLabel         matlab.ui.control.Label
        CentroideLabel               matlab.ui.control.Label
        lbResSVM                     matlab.ui.control.Label
        lbResCentroide               matlab.ui.control.Label
        Clasificador                 matlab.ui.container.Tab
        tbMatrizEntrena              matlab.ui.control.Table
        Label                        matlab.ui.control.Label
        MuestrasSanasEditFieldLabel  matlab.ui.control.Label
        txtMSanas                    matlab.ui.control.EditField
        MuestrasAFEditFieldLabel     matlab.ui.control.Label
        txtMAF                       matlab.ui.control.EditField
        Image8                       matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function plotOriginal(app)
            plot(app.AxesDT,evalin("base","ecg"))
            title(app.AxesDT,"Señal Original");
            legend(app.AxesDT,"Señal ECG Original");
        end
        function plotProcesada(app)
            plot(app.AxesDT,evalin("base","ecgP"))
            title(app.AxesDT,"Señal Procesada")
            legend(app.AxesDT,"Señal ECG Procesada");
        end
        function plotOrigProc(app)
            plot(app.AxesDT,evalin("base","ecg"))
            hold(app.AxesDT,"on")
            plot(app.AxesDT,evalin("base","ecgP"))
            hold(app.AxesDT,"off")
            title(app.AxesDT,"Señal Original vs Señal Procesada")
            legend(app.AxesDT,'Señal Original','Señal Procesada');
        end
        function plotComplexQRS(app)
            %%
            app.cbOriginal.Enable=false;
            %%
            hold(app.AxesDT,"on")
            scatter(app.AxesDT,evalin("base","Qx"),evalin("base","Qy"),"kO","MarkerFaceColor","k")
            scatter(app.AxesDT,evalin("base","Rx"),evalin("base","Ry"),"rs","MarkerFaceColor","r")
            scatter(app.AxesDT,evalin("base","Sx"),evalin("base","Sy"),"cO","MarkerFaceColor","c")
            %%
            hold(app.AxesDT,"off")
        end
        function plotColorQRS(app)
            hold(app.AxesDT,"on")
            % COLOREAMOS EL COMPLEJO QRS
            vecQx=evalin("base","Qx");
            vecSx=evalin("base","Sx");
            t=evalin("base","t");
            ecg=evalin("base","ecgP");
            for i=1:length(vecSx)-1
                plot(app.AxesDT,t(vecQx(i):vecSx(i)),ecg(vecQx(i):vecSx(i)),'Color',[0.5,0.5,0.5],'LineWidth',1)
            end
            hold(app.AxesDT,"off")
        end
        function plotPicoP(app)
            app.cbOriginal.Enable=false;
            hold(app.AxesDT,"on")
            if ~isempty(evalin("base","Px")) && ~isempty(evalin("base","Py"))
            scatter(app.AxesDT,evalin("base","Px"),evalin("base","Py"),"mO","MarkerFaceColor","m");
            end
            hold(app.AxesDT,"off")
            title(app.AxesDT,"Señal ECG - P")
            %legend(app.AxesDT,"Señal ECG","P")
        end
        function plotIntervaloRR(app)
            ry=evalin("base","Ry");
            dist=max(ry)*1.25;
            % Rayas Verticales
            rx=evalin("base","Rx");
            Fs=evalin("base","Fs");
            intRR=diff(rx)/Fs;
            hold(app.AxesDT,"on")
            for i=1:length(rx)
                plot(app.AxesDT,[rx(i) rx(i)],[ry(i) dist*1.05],'k--','LineWidth',1)
                if i<length(rx)
                    text(app.AxesDT,rx(i),dist*1.05,num2str(intRR(i)))
                end
            end
            % Raya Horizontal
            plot(app.AxesDT,[rx(1) rx(length(rx))],[dist dist],'k--','LineWidth',1)
            hold(app.AxesDT,"off")
        end
        function plotTaq(app)
            rx=evalin("base","Rx");
            ry=evalin("base","Ry");
            t1=evalin("base","t1");
            tR=t1(rx);
            % Intervalo Normal entre R-R
            % 60 bpm -> Intervalo R-R es 1 seg
            % 100 bpm -> Intervalo R-R es 0.6 seg
            delta_tR = zeros(length(rx)-1,1);
            disp('====================================')
            disp('  INTERVALO DE TIEMPO R-R (Normal)  ')
            fprintf('60 lpm = 1 seg\n100 lpm = 0.6 seg\n')
            hold(app.AxesDT,"on")
            for j=1:length(delta_tR)
                delta_tR(j)=tR(j+1)-tR(j);
                Fc=60*(1/delta_tR(j));% Frec. Card - Método Tradicional
                % Comprobamos si el Intervalo R-R está dentro del rango normal
                if(delta_tR(j)>=0.6 && delta_tR(j)<=1)
                    interValor = 1;% Almacenamos 1=Intervalo Normal
                    fprintf('Intervalo R-R = %0.5f >> NORMAL, FC = %d\n',delta_tR(j),round(Fc));
                elseif(delta_tR(j)<0.6) % Cuando el Intervalo R-R es <0.6 -> Taquicardia
                    interValor = 2;% Almacenamos 2=Intervalo Taquicardia
                    % Obtenemos las posiciones "x","y" de los Picos del Intervalo R-R
                    yTaq=[ry(j) ry(j+1)];
                    xTaq=[rx(j) rx(j+1)];
                    fprintf('Intervalo R-R = %0.5f >> TAQUICARDIA, FC = %d\n',delta_tR(j),round(Fc));
                    % Creamos una línea que une a cada Intervalo R-R y colocamos el
                    % intervalo que posee
                    plot(app.AxesDT,xTaq,yTaq,'r','LineWidth',1)
                    % Graficamos los Picos R donde no cumplen en Intervalo R-R normal
                    %gscatter(app.AxesDT,xTaq,yTaq)
                    text(app.AxesDT,mean(xTaq(1))*1.035,mean(yTaq(1))*1.035,strcat("FC:",num2str(round(Fc))))
                elseif(delta_tR(j)>1) % Cuando Intervalo R-R es > 1 -> Bradicardia
                    interValor = 3;% Alamacenamos 3=Intervalo Bradicardia
                end
                % Guardamos en un vector al intervalo y su característica Norm,Taq,Bra
                %intervaloRR(j,1:2)=[delta_tR(j),interValor];
            end
            hold(app.AxesDT,"off")
        end
        %%Funciones para inhabilitar los check
        function bloqCheckD(app)
            app.cbOriginal.Enable=false;
            app.cbDistancia.Enable=true;
        end
        function desbloqCheckD(app)
            app.cbOriginal.Enable=true;
            app.cbDistancia.Enable=false;
        end
        function bloqCheckT(app)
            app.cbOriginal.Enable=false;
            app.cbPQRS.Enable=true;
        end
        function desbloqCheckT(app)
            app.cbOriginal.Enable=true;
            app.cbPQRS.Enable=false;
        end
        function reseteoCarga(app)
     app.cbDistancia.Value=0;
            app.cbDistancia.Enable=false;
            app.cbPQRS.Value=0;
            app.cbPQRS.Enable=false;
            app.cbProcesada.Value=0;
            app.cbProcesada.Enable=true;
            app.cbOriginal.Value=0;
            app.cbOriginal.Enable=true;
            app.txtFrecuencia.Value="";
            app.txtGain.Value="";
            app.txtMensaje.Value="";
            app.txtABanda.Value="";
            app.txtFMedia.Value="";
            app.txtDistAF.Value="";
            app.txtDistSana.Value="";
            app.lbResCentroide.Text="";
            app.lbResSVM.Text="";
            app.tbCaracteristica.Data=[];
            app.btnSVM.Enable=false;
            app.btnCentroide.Enable=false;
            cla(app.AxesDF,"reset");
            cla(app.AxesDT,"reset");
            evalin("base","clear all")
            app.startupFcn;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;     
            generador();
            matriz2Entrenar=evalin("base","matriz2Entrenar");
            app.tbMatrizEntrena.Data=matriz2Entrenar;
            contSanas=sum(matriz2Entrenar{:,7}==0);
            contAF=sum(matriz2Entrenar{:,7}==1);
            app.txtMSanas.Value=num2str(contSanas);
            app.txtMAF.Value=num2str(contAF);
        end

        % Menu selected function: CargarSealMenu
        function CargarSealMenuSelected(app, event)
            clc; 
            app.reseteoCarga;
            try
                [filename,pathname]=uigetfile('*.mat*','Carga de Señal ECG');
                fprintf('Archivo: %s\nPath: %s\n',filename,pathname);
                if(filename~="")
                    %%Cargamos la Señal
                    senial=load(strcat(pathname,filename));
                    inputData=["Frecuencia","Gain"];
                    data=inputdlg(inputData,'Ingrese los Parámetros',[1 30]);
                    
                    Fs=str2double(data(1));
                    Gain=str2double(data(2));
                    %%Pasamos valores a Fs y Gain
                    app.txtFrecuencia.Value=num2str(Fs);
                    app.txtGain.Value=num2str(Gain);
                    %%Estandarizamos
                    [t,ecg]=estandarizar(senial.val(1,:),Fs,0,Gain);
                    t1=t;
                    %%Pasamos datos al Workspace
                    assignin("base","senialECG",senial.val(1,:));
                    assignin("base","Fs",Fs);
                    assignin("base","Gain",Gain);
                    assignin("base","t1",t1);
                    assignin("base","ecg",ecg);
                    %%Complejo QRS
                    [ecgP,Qx,Qy,Rx,Ry,Sx,Sy]=QRS(ecg,Fs);
                    assignin("base","ecgP",ecgP);
                    assignin("base","Qx",Qx);
                    assignin("base","Qy",Qy);
                    assignin("base","Rx",Rx);
                    assignin("base","Ry",Ry);
                    assignin("base","Sx",Sx);
                    assignin("base","Sy",Sy);
                    %%Segmento P
                    t=(1:length(ecgP));
                    assignin("base","t",t);
                    [Px,Py]=P(ecgP,t,Rx,Fs);
                    
                    assignin("base","Px",Px);
                    assignin("base","Py",Py);
                    %%Filename y Pathname
                    assignin("base","filename",filename);
                    assignin("base","pathname",pathname);
                end
            catch
                 
            end
        end

        % Value changed function: cbOriginal
        function cbOriginalValueChanged(app, event)
            value = app.cbOriginal.Value;
            valueP = app.cbProcesada.Value;
            if(value==1 && valueP==1)%Original y Procesada
                %%
                app.cbPQRS.Enable=false;
                app.cbDistancia.Enable=false;
                %%
                app.plotOrigProc;
            elseif(value==1 && valueP==0)%Original y NO-Procesada
                app.plotOriginal;
            elseif(value==0 && valueP==1)%No-Original y Procesada
                %%
                app.cbPQRS.Enable=true;
                %%
                app.plotProcesada;
            elseif(value==0 && valueP==0)%No-Original y No-Procesada
                %%
                app.cbPQRS.Enable=false;
                app.cbDistancia.Enable=false;
                %%
                cla(app.AxesDT,"reset")
            end
        end

        % Value changed function: cbProcesada
        function cbProcesadaValueChanged(app, event)
            value = app.cbProcesada.Value;
            valueO = app.cbOriginal.Value;
            if(value==1 && valueO==1)%Procesada y Original
                app.plotOrigProc;
            elseif(value==1 && valueO==0)%Procesada y No-Original
                %%
                app.cbPQRS.Enable=true;
                app.cbDistancia.Enable=false;
                %%
                app.plotProcesada;
            elseif(value==0 && valueO==1)%No-Procesada y Original
                %%
                app.cbPQRS.Enable=false;
                %%
                app.plotOriginal;
            elseif(value==0 && valueO==0)%Procesada y No-Original
                %%
                app.cbPQRS.Enable=false;
                app.cbDistancia.Enable=false;
                %%
                cla(app.AxesDT,"reset")
            end
        end

        % Value changed function: cbPQRS
        function cbPQRSValueChanged(app, event)
            value = app.cbPQRS.Value;
            if(value==1)
                cla(app.AxesDT,"reset")
                app.plotProcesada
                app.plotComplexQRS
                if ~isempty(evalin("base","Px")) && ~isempty(evalin("base","Py"))
                    app.plotPicoP
                    legend(app.AxesDT,"Señal ECG","Q","R","S","P")
                    title(app.AxesDT,"PQRS")
                else
                    legend(app.AxesDT,"Señal ECG","Q","R","S")
                    app.txtMensaje.Value="En esta señal presenta" + ...
                        " ausencia de Pico P en ese instante de tiempo por eso" + ...
                        " no se visualiza en la gráfica.";
                    
                    title(app.AxesDT,"PQRS")
                end
                %
                app.cbDistancia.Enable=true;
                app.cbProcesada.Enable=false;
                %
            elseif(value==0)
                app.cbDistancia.Value=0;
                app.cbDistancia.Enable=false;
                app.cbOriginal.Enable=true;
                app.cbProcesada.Enable=true;
                app.plotProcesada
            end
        end

        % Value changed function: cbDistancia
        function cbDistanciaValueChanged(app, event)
            value = app.cbDistancia.Value;
            valueQRS = app.cbPQRS.Value;
            if(value==1)
                app.plotIntervaloRR;
                app.plotTaq;
                if ~isempty(evalin("base","Px")) && ~isempty(evalin("base","Py"))
                    legend(app.AxesDT,"Señal Procesada","Q","R","S","P","Intervalo R-R")
                    title(app.AxesDT,"PQRS")
                else
                    legend(app.AxesDT,"Señal Procesada","Q","R","S","Intervalo R-R")
                    title(app.AxesDT,"PQRS")
                end
                app.cbPQRS.Enable=false;
                app.cbProcesada.Enable=false;
            elseif(value==0 && valueQRS==1)
                app.cbPQRS.Enable=true;
                app.cbProcesada.Enable=true;
                app.cbPQRSValueChanged;
            end
        end

        % Button pushed function: btnSVM
        function btnSVMPushed(app, event)
            %Variables
            tablaCaract=evalin("base","tablaCaract");
            pr=evalin("base","pr");
            modelo=evalin("base","modelo");
            tablaCaractFinal=[]; %Esta matriz será para almancenar los datos q se va a predecir
            yfit = modelo.predictFcn(tablaCaract);%Mando a predecir una señal de prueba con el modelo
            %1 son señales enfermas y 0 las sanas
            %Guardo la tabla en una matriz para ver los resultados
            tablaCaractFinal =[tablaCaractFinal; table(pr(1),pr(2),pr(3),pr(4),pr(5),pr(6),yfit,'VariableNames',{'potenci','frec','ancho','poder','men','med','Prediccion'})];
            tbCaractFinal=table(pr(1),pr(2),pr(3),pr(4),pr(5),pr(6),yfit);
            app.tbCaracteristica.Data=tbCaractFinal;
            %Si la predicción es 1 es una señal posiblemente con fibrilación, caso
            %contrario será posiblemente sana
            if yfit==1
                disp('SVM-Señal posiblemente con fibrilación')
                %msgbox('Señal posiblemente con fibrilación','Resultado');
                app.lbResSVM.Text="Posible Fibrilación";
            else
                disp('SVM-Señal posiblemente Sana')
                %msgbox('Señal posiblemente Sana','Resultado');
                app.lbResSVM.Text="Señal Sana";
            end
            assignin("base","tablaCaractFinal",tablaCaractFinal);
        end

        % Button pushed function: btnCentroide
        function btnCentroideButtonPushed(app, event)
            %Variables
            file=evalin("base","filename");
            path=evalin("base","pathname");
            fs=evalin("base","Fs");
            gain=evalin("base","Gain");
            ejeXSano=evalin("base","ejeXSano");
            ejeYSano=evalin("base","ejeYSano");
            ejeXAF=evalin("base","ejeXAF");
            ejeYAF=evalin("base","ejeYAF");
            %Extraigo la característica de la Señal que está cargada
            matrizPruebaCent=extrCaract(strcat(path,file),gain,fs);
            %La nube de puntos fue formado en base al ancho de banda y la media de la
            %frecuencia al igual que lo hicimos con con Machine Learning Quadratic SVM
            %Grafico la nube de puntos de las señales sanas
            scatter(app.AxesDF,ejeXSano,ejeYSano,'db','filled') %con d indico que es un rombo
            %con b indico que va ser de colora azul, y con filled relleno el rombo
            hold(app.AxesDF,"on")
            scatter(app.AxesDF,ejeXAF,ejeYAF,60,'pr','filled') %con p indico que es una estrella
            vecSano=[ejeXSano; ejeYSano]'; %Genero el vector Sano en x e y
            vecAF=[ejeXAF;ejeYAF]'; %Genero el vector de fibriación en x e y
            [idx1,C] = kmeans(vecSano,1); %Genero el centroide Sano
            CSan=C; %guardo de manera temporal el centroide sano
            [idx2,C] = kmeans(vecAF,1); %Genero los centroides de Fibrilación
            CFib=C;
            plot(app.AxesDF,CSan(1),CSan(2),'bx','MarkerSize',15,'LineWidth',3) %Grafico el centroide sano
            plot(app.AxesDF,CFib(1),CFib(2),'rx','MarkerSize',15,'LineWidth',3) %Grafico el centroi de fibrilación
            m=matrizPruebaCent;
            plot(app.AxesDF,m(3),m(5),'mO','LineWidth',2) %Grafico señal entrada
            legend(app.AxesDF,"Nube Sana","Nube AF","Centroide Sano","Centriode AF","Señal Ingresada");
            %Calculo las distancias euclidianas
            %Distancia del la señal de prueba hacia el centroide Sano
            dSano1=norm([matrizPruebaCent(3) matrizPruebaCent(5)]-[CSan(1) CSan(2)]);
            %Distancia de la señal de prueba hacia el centroide 1 de Fibrilación
            dAF1=norm([matrizPruebaCent(3) matrizPruebaCent(5)]-[CFib(1) CFib(2)]);
            %Distancia de la señal de prueba hacia el centroide 2 de Fibrilación
            %dAF2=norm([matrizPruebaCent(i,3) matrizPruebaCent(i,5)]-[C(2,1) C(2,2)]);
            %Obtengo el mínimo de las 3 distancias y su posición
            %[minD, pos]=min([dSano1 dAF1 dAF2]);
            [minD, pos]=min([dSano1 dAF1]);
            %De acuerdo a su posición, como la primera corresponde a una predicción
            %sana, 2 y 3 a un predicción de fibrilación
            if pos==1
                disp('Centroide-Posiblemente Sana')
                %msgbox('Señal posiblemente Sana','Resultado');
                app.lbResCentroide.Text="Señal Sana";
            else
                disp('Centroide-Posiblemente con Fibrilación')
                %msgbox('Señal posiblemente con fibrilación','Resultado');
                app.lbResCentroide.Text="Posible Fibrilación";
            end
            hold(app.AxesDF,"off")
            app.txtDistAF.Value=num2str(dAF1);
            app.txtDistSana.Value=num2str(dSano1);
        end

        % Button pushed function: btnCaracteristica
        function btnCaracteristicaButtonPushed(app, event)
            %Variables
            file=evalin("base","filename");
            path=evalin("base","pathname");
            fs=evalin("base","Fs");
            gain=evalin("base","Gain");
            %Generamos los datos del modelo
           
            pr=extrCaract(strcat(path,file),gain,fs);
            assignin("base","pr",pr);
            tbCaract=table(pr(1),pr(2),pr(3),pr(4),pr(5),pr(6));
            %creo una tabla de las caracteristicas de cada señal
            tablaCaract=table(pr(1),pr(2),pr(3),pr(4),pr(5),pr(6),'VariableNames',{'potenci','frec','ancho','poder','men','med'});
            app.tbCaracteristica.Data=tbCaract;
            assignin("base","tablaCaract",tablaCaract);
            %Habilito Botón analizar
            app.btnSVM.Enable=true;
            app.btnCentroide.Enable=true;
            %Paso valores al txt
            app.txtABanda.Value=num2str(pr(3));
            app.txtFMedia.Value=num2str(pr(5));
            %Matriz Entrenamiento
            matriz2Entrenar=evalin("base","matriz2Entrenar");
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AtrialFibrilationApp = uifigure('Visible', 'off');
            app.AtrialFibrilationApp.Name = 'AtrialFibrilationApp';
            app.ArchivoMenu = uimenu(app.AtrialFibrilationApp);
            app.ArchivoMenu.Text = 'Archivo';
            app.CargarSealMenu = uimenu(app.ArchivoMenu);
            app.CargarSealMenu.MenuSelectedFcn = createCallbackFcn(app, @CargarSealMenuSelected, true);
            app.CargarSealMenu.Text = 'Cargar Señal';
            app.TabGroup = uitabgroup(app.AtrialFibrilationApp);
            app.InicioTab = uitab(app.TabGroup);
            app.InicioTab.Title = 'Inicio';
            app.Image2 = uiimage(app.InicioTab);
            app.Image2.ImageSource = 'corazon_gif.gif';
            app.Image4 = uiimage(app.InicioTab);
            app.Image4.ImageSource = 'corazon_gif.gif';
            app.Image3 = uiimage(app.InicioTab);
            app.Image3.ImageSource = 'tablaCar.jpg';
            app.INTEGRANTESESTALINGUNZAMICHAELPONCELabel = uilabel(app.InicioTab);
            app.INTEGRANTESESTALINGUNZAMICHAELPONCELabel.Text = {'INTEGRANTES:'; ''; '    - ESTALIN GUNZA'; '    - MICHAEL PONCE'};
            app.SealTab = uitab(app.TabGroup);
            app.SealTab.Title = 'Señal';
            app.Image7 = uiimage(app.SealTab);
            app.Image7.ImageSource = 'sanaVsAF.png';
            app.ParmetrosTab = uitab(app.TabGroup);
            app.ParmetrosTab.Title = 'Parámetros';
            app.Image6 = uiimage(app.ParmetrosTab);
            app.Image6.ImageSource = 'senial.png';
            app.Inicio = uitab(app.TabGroup);
            app.Inicio.Title = 'Proceso';
            app.Image5 = uiimage(app.Inicio);
            app.Image5.ImageSource = 'proceso.png';
            app.DomT = uitab(app.TabGroup);
            app.DomT.Title = 'D. Tiempo';
            app.AxesDT = uiaxes(app.DomT);
            title(app.AxesDT, '')
            xlabel(app.AxesDT, '')
            ylabel(app.AxesDT, '')
            app.SeleccionelagrficaquedeseeaobservarLabel = uilabel(app.DomT);
            app.SeleccionelagrficaquedeseeaobservarLabel.Text = 'Seleccione la gráfica que deseea observar';
            app.FrecuenciaHzEditFieldLabel = uilabel(app.DomT);
            app.FrecuenciaHzEditFieldLabel.Text = 'Frecuencia(Hz)';
            app.txtFrecuencia = uieditfield(app.DomT, 'text');
            app.txtFrecuencia.Editable = 'off';
            app.ParmetrosdelaSealECGingresadaLabel = uilabel(app.DomT);
            app.ParmetrosdelaSealECGingresadaLabel.Text = 'Parámetros de la Señal ECG ingresada';
            app.GainEditFieldLabel = uilabel(app.DomT);
            app.GainEditFieldLabel.Text = 'Gain';
            app.txtGain = uieditfield(app.DomT, 'text');
            app.txtGain.Editable = 'off';
            app.cbOriginal = uicheckbox(app.DomT);
            app.cbOriginal.ValueChangedFcn = createCallbackFcn(app, @cbOriginalValueChanged, true);
            app.cbOriginal.Text = 'Señal Original';
            app.cbProcesada = uicheckbox(app.DomT);
            app.cbProcesada.ValueChangedFcn = createCallbackFcn(app, @cbProcesadaValueChanged, true);
            app.cbProcesada.Text = 'Señal Procesada';
            app.cbPQRS = uicheckbox(app.DomT);
            app.cbPQRS.ValueChangedFcn = createCallbackFcn(app, @cbPQRSValueChanged, true);
            app.cbPQRS.Enable = 'off';
            app.cbPQRS.Text = 'PQRS';
            app.cbDistancia = uicheckbox(app.DomT);
            app.cbDistancia.ValueChangedFcn = createCallbackFcn(app, @cbDistanciaValueChanged, true);
            app.cbDistancia.Enable = 'off';
            app.cbDistancia.Text = 'Distancias';
            app.txtMensaje = uitextarea(app.DomT);
            app.txtMensaje.Editable = 'off';
            app.DomF = uitab(app.TabGroup);
            app.DomF.Title = 'D. Frecuencia';
            app.AxesDF = uiaxes(app.DomF);
            title(app.AxesDF, 'Nube Sana vs Nube AF')
            xlabel(app.AxesDF, 'Ancho de Banda')
            ylabel(app.AxesDF, 'F. Media')
            app.SeleccioneelarchivoquedeseaanalizarLabel = uilabel(app.DomF);
            app.SeleccioneelarchivoquedeseaanalizarLabel.Text = 'Para obtener la Matriz de Carcaterística de click en el botón:';
            app.MACHINELEARNINGPanel = uipanel(app.DomF);
            app.MACHINELEARNINGPanel.Title = 'MACHINE LEARNING';
            app.btnSVM = uibutton(app.MACHINELEARNINGPanel, 'push');
            app.btnSVM.ButtonPushedFcn = createCallbackFcn(app, @btnSVMPushed, true);
            app.btnSVM.Enable = 'off';
            app.btnSVM.Text = 'Analizar con SVM';
            app.tbCaracteristica = uitable(app.MACHINELEARNINGPanel);
            app.tbCaracteristica.ColumnName = {'Potencia'; 'Frecuencia'; 'Ancho Banda'; 'Poder'; 'Media'; 'Mediana'; 'Clasificación'};
            app.tbCaracteristica.RowName = {};
            app.DISTANCIAALCENTROIDEPanel = uipanel(app.DomF);
            app.DISTANCIAALCENTROIDEPanel.Title = 'DISTANCIA AL CENTROIDE';
            app.btnCentroide = uibutton(app.DISTANCIAALCENTROIDEPanel, 'push');
            app.btnCentroide.ButtonPushedFcn = createCallbackFcn(app, @btnCentroideButtonPushed, true);
            app.btnCentroide.Enable = 'off';
            app.btnCentroide.Text = 'Centroide';
            app.AnchodeBandaEditFieldLabel = uilabel(app.DISTANCIAALCENTROIDEPanel);
            app.AnchodeBandaEditFieldLabel.Text = 'Ancho de Banda';
            app.txtABanda = uieditfield(app.DISTANCIAALCENTROIDEPanel, 'text');
            app.txtABanda.Editable = 'off';
            app.FMediaEditFieldLabel = uilabel(app.DISTANCIAALCENTROIDEPanel);
            app.FMediaEditFieldLabel.Text = 'F. Media';
            app.txtFMedia = uieditfield(app.DISTANCIAALCENTROIDEPanel, 'text');
            app.txtFMedia.Editable = 'off';
            app.DistanciasLabel = uilabel(app.DISTANCIAALCENTROIDEPanel);
            app.DistanciasLabel.Text = 'Distancias:';
            app.CentroideSanaLabel = uilabel(app.DISTANCIAALCENTROIDEPanel);
            app.CentroideSanaLabel.Text = 'Centroide Sana';
            app.txtDistSana = uieditfield(app.DISTANCIAALCENTROIDEPanel, 'text');
            app.txtDistSana.Editable = 'off';
            app.CentroideAFLabel = uilabel(app.DISTANCIAALCENTROIDEPanel);
            app.CentroideAFLabel.Text = 'Centroide AF';
            app.txtDistAF = uieditfield(app.DISTANCIAALCENTROIDEPanel, 'text');
            app.txtDistAF.Editable = 'off';
            app.btnCaracteristica = uibutton(app.DomF, 'push');
            app.btnCaracteristica.ButtonPushedFcn = createCallbackFcn(app, @btnCaracteristicaButtonPushed, true);
            app.btnCaracteristica.Text = 'Obtener';
            app.ResultadodeAnlisisLabel = uilabel(app.DomF);
            app.ResultadodeAnlisisLabel.Text = 'Resultado de Análisis';
            app.MachineLearningLabel = uilabel(app.DomF);
            app.MachineLearningLabel.Text = '- Machine Learning:';
            app.CentroideLabel = uilabel(app.DomF);
            app.CentroideLabel.Text = '- Centroide:';
            app.lbResSVM = uilabel(app.DomF);
            app.lbResSVM.Text = '';
            app.lbResCentroide = uilabel(app.DomF);
            app.lbResCentroide.Text = '';
            app.Clasificador = uitab(app.TabGroup);
            app.Clasificador.Title = 'Clasificación';
            app.tbMatrizEntrena = uitable(app.Clasificador);
            app.tbMatrizEntrena.ColumnName = {'Potencia'; 'Frecuencia'; 'Ancho Banda'; 'Poder'; 'Media'; 'Mediana'; 'Clasificación'};
            app.tbMatrizEntrena.RowName = {};
            app.Label = uilabel(app.Clasificador);
            app.Label.Text = 'Matriz de características para el entrenamiento de Machine Learnig SVM';
            app.MuestrasSanasEditFieldLabel = uilabel(app.Clasificador);
            app.MuestrasSanasEditFieldLabel.Text = 'Muestras Sanas';
            app.txtMSanas = uieditfield(app.Clasificador, 'text');
            app.txtMSanas.Editable = 'off';
            app.MuestrasAFEditFieldLabel = uilabel(app.Clasificador);
            app.MuestrasAFEditFieldLabel.Text = 'Muestras AF';
            app.txtMAF = uieditfield(app.Clasificador, 'text');
            app.txtMAF.Editable = 'off';
            app.Image8 = uiimage(app.Clasificador);
            app.Image8.ImageSource = 'matrixConf.png';
            % Show the figure after all components are created
            app.AtrialFibrilationApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AtrialFibrilationApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AtrialFibrilationApp)
        end
    end
end